classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        VersionDropDownLabel          matlab.ui.control.Label
        VersionDropDown               matlab.ui.control.DropDown
        PowerCheckBox                 matlab.ui.control.CheckBox
        CoherenceCheckBox             matlab.ui.control.CheckBox
        GrangerCheckBox               matlab.ui.control.CheckBox
        FourierTransformCheckBox      matlab.ui.control.CheckBox
        LinearDirectionalityCheckBox  matlab.ui.control.CheckBox
        VersionDropDown_2Label        matlab.ui.control.Label
        VersionDropDown_2             matlab.ui.control.DropDown
        VersionDropDown_3Label        matlab.ui.control.Label
        VersionDropDown_3             matlab.ui.control.DropDown
        VersionDropDown_4Label        matlab.ui.control.Label
        VersionDropDown_4             matlab.ui.control.DropDown
        VersionDropDown_5Label        matlab.ui.control.Label
        VersionDropDown_5             matlab.ui.control.DropDown
        Button                        matlab.ui.control.Button
        LPNEDataAnalyisPipelineGUILabel  matlab.ui.control.Label
        PushthebuttontostartfeatureextractionLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Therehavebeennochangestocoherencesincev11Label_2  matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        v15istheonlyversionofthesefeaturesLabel  matlab.ui.control.Label
        NumberofCoresEditFieldLabel   matlab.ui.control.Label
        NumberofCoresEditField        matlab.ui.control.NumericEditField
        Label_4                       matlab.ui.control.Label
        MVGCtoolboxlocationonlyneededforGrangerEditFieldLabel  matlab.ui.control.Label
        MVGCtoolboxlocationonlyneededforGrangerEditField  matlab.ui.control.EditField
        Label_5                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        Label_10                      matlab.ui.control.Label
        Label_11                      matlab.ui.control.Label
        a____Label                    matlab.ui.control.Label
    end


    methods (Access = public)
        
        function options = getOptions(app) % Return the state of the GUI
            options.parCores = app.NumberofCoresEditField.Value;
            options.featureList = {};
            if app.PowerCheckBox.Value
                options.featureList = [options.featureList, {'power'}];
                options.version.power = app.VersionDropDown.Value;
            end
            if app.CoherenceCheckBox.Value
                options.featureList = [options.featureList, {'coherence'}];
                options.version.coherence = app.VersionDropDown_2.Value;
            end
            if app.GrangerCheckBox.Value
                options.featureList = [options.featureList, {'granger'}];
                options.version.granger = app.VersionDropDown_3.Value;
                % MVGC folder
                options.mvgcFolder = app.MVGCtoolboxlocationonlyneededforGrangerEditField.Value;
            end
            if app.FourierTransformCheckBox.Value
                options.featureList = [options.featureList, {'fft'}];
                options.version.fft = app.VersionDropDown_4.Value;
            end
            if app.LinearDirectionalityCheckBox.Value
                options.featureList = [options.featureList, {'causality'}];
                options.version.causality = app.VersionDropDown_5.Value;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VersionDropDownLabel = uilabel(app.UIFigure);
            app.VersionDropDownLabel.Text = 'Version:';
            app.VersionDropDown = uidropdown(app.UIFigure);
            app.VersionDropDown.Items = {'1.1', 'saveFeatures_1.2', 'caus_saveFeatures_1.5'};
            app.VersionDropDown.Value = 'saveFeatures_1.2';
            app.PowerCheckBox = uicheckbox(app.UIFigure);
            app.PowerCheckBox.Text = 'Power';
            app.CoherenceCheckBox = uicheckbox(app.UIFigure);
            app.CoherenceCheckBox.Text = 'Coherence';
            app.GrangerCheckBox = uicheckbox(app.UIFigure);
            app.GrangerCheckBox.Text = 'Granger';
            app.FourierTransformCheckBox = uicheckbox(app.UIFigure);
            app.FourierTransformCheckBox.Text = 'Fourier Transform';
            app.LinearDirectionalityCheckBox = uicheckbox(app.UIFigure);
            app.LinearDirectionalityCheckBox.Text = 'Linear Directionality';
            app.VersionDropDown_2Label = uilabel(app.UIFigure);
            app.VersionDropDown_2Label.Text = 'Version:';
            app.VersionDropDown_2 = uidropdown(app.UIFigure);
            app.VersionDropDown_2.Items = {'1.1'};
            app.VersionDropDown_2.Value = '1.1';
            app.VersionDropDown_3Label = uilabel(app.UIFigure);
            app.VersionDropDown_3Label.Text = 'Version:';
            app.VersionDropDown_3 = uidropdown(app.UIFigure);
            app.VersionDropDown_3.Items = {'1.1', 'saveFeatures_1.5'};
            app.VersionDropDown_3.Value = 'saveFeatures_1.5';
            app.VersionDropDown_4Label = uilabel(app.UIFigure);
            app.VersionDropDown_4Label.Text = 'Version:';
            app.VersionDropDown_4 = uidropdown(app.UIFigure);
            app.VersionDropDown_4.Items = {'1.1', 'saveFeatures_1.2', ''};
            app.VersionDropDown_4.Value = 'saveFeatures_1.2';
            app.VersionDropDown_5Label = uilabel(app.UIFigure);
            app.VersionDropDown_5Label.Text = 'Version:';
            app.VersionDropDown_5 = uidropdown(app.UIFigure);
            app.VersionDropDown_5.Items = {'saveFeatures_1.5'};
            app.VersionDropDown_5.Value = 'saveFeatures_1.5';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.LPNEDataAnalyisPipelineGUILabel = uilabel(app.UIFigure);
            app.LPNEDataAnalyisPipelineGUILabel.Text = 'LPNE Data Analy$is Pipeline GUI';
            app.PushthebuttontostartfeatureextractionLabel = uilabel(app.UIFigure);
            app.PushthebuttontostartfeatureextractionLabel.Text = 'Push the button to start feature extraction:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'v1.1 calculates power using standard scaling,'; 'which changes based on frequency resolutions'; ''; 'v1.2 uses spectral density scaling, '; 'which is invariant to frequency resolution'; ''; 'v1.2 is identical to versions 1.3 through 1.5'; '(but not version ''caus_saveFeatures_1.5'')'};
            app.Therehavebeennochangestocoherencesincev11Label_2 = uilabel(app.UIFigure);
            app.Therehavebeennochangestocoherencesincev11Label_2.Text = 'There have been no changes to coherence since v1.1';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'v1.1 runs the data through a high pass filter above 4Hz'; ''; 'This increases numerical stability,'; 'by making the data look more ''stationary'''; ''; 'v1.5 removes that filtering to get ''raw'' Granger Causality'};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'v1.1 uses the scaling that was used in the original CSFA code'; ''; 'v1.2 uses standard scaling,'; 'that is compatible with all current versions of the CSFA code'; ''};
            app.v15istheonlyversionofthesefeaturesLabel = uilabel(app.UIFigure);
            app.v15istheonlyversionofthesefeaturesLabel.Text = 'v1.5 is the only version of these features';
            app.NumberofCoresEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofCoresEditFieldLabel.Text = 'Number of Cores:';
            app.NumberofCoresEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofCoresEditField.Limits = [0 32];
            app.NumberofCoresEditField.RoundFractionalValues = 'on';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'Having more than one core allows the feature generation to be'; 'parallelized, speeding things up. Setting to 0 will not use '; 'parallel computing. If you''re using a laptop, set to 0 or 2.'; 'On Thor, Wayne, and Bruce, 8 cores is reasonable.'};
            app.MVGCtoolboxlocationonlyneededforGrangerEditFieldLabel = uilabel(app.UIFigure);
            app.MVGCtoolboxlocationonlyneededforGrangerEditFieldLabel.Text = 'MVGC toolbox location (only needed for Granger):';
            app.MVGCtoolboxlocationonlyneededforGrangerEditField = uieditfield(app.UIFigure, 'text');
            app.MVGCtoolboxlocationonlyneededforGrangerEditField.Value = '~/lpne-data-analysis/mvgc/';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = {'$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'; '$'};
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~';
            app.a____Label = uilabel(app.UIFigure);
            app.a____Label.Text = {''; '                                      ____    .-.'; '                                  .-"`    `",( __\_'; '                   .-==:;-._    .''         .-.     `''.'; '                 .''      `"-:''-/          (  \} -=a  .)'; '                /            \/       \,== `-  __..-''`'; '             ''-''              |       |   |  .''\ `;'; '                               \    _/---''\ (   `"`'; '                              /.`._ )      \ `;'; '                              \`-/.''        `"`'; '                               `"\`-.'; '                                  `"`'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end